classdef pid_main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ManualControlPanel             matlab.ui.container.Panel
        VialValvesPanel                matlab.ui.container.Panel
        PresurizationPanel             matlab.ui.container.Panel
        PressurizeOdorLamp             matlab.ui.control.Lamp
        PressurizeOdorVialSwitch       matlab.ui.control.Switch
        PressurizeOdorVialSwitchLabel  matlab.ui.control.Label
        PressurizeSolventLamp          matlab.ui.control.Lamp
        PressurizeSolventVialSwitch    matlab.ui.control.Switch
        PressurizeSolventVialSwitchLabel  matlab.ui.control.Label
        OdorVialBottlePanel            matlab.ui.container.Panel
        Valve3Lamp                     matlab.ui.control.Lamp
        Valve3Switch                   matlab.ui.control.Switch
        Valve3SwitchLabel              matlab.ui.control.Label
        Valve4Lamp                     matlab.ui.control.Lamp
        Valve4Switch                   matlab.ui.control.Switch
        Valve4SwitchLabel              matlab.ui.control.Label
        SolventPureVialPanel           matlab.ui.container.Panel
        Valve1Lamp                     matlab.ui.control.Lamp
        Valve1Switch                   matlab.ui.control.Switch
        Valve1SwitchLabel              matlab.ui.control.Label
        Valve2Lamp                     matlab.ui.control.Lamp
        Valve2Switch                   matlab.ui.control.Switch
        Valve2SwitchLabel              matlab.ui.control.Label
        FinalValvesPanel               matlab.ui.container.Panel
        OdorFVLamp                     matlab.ui.control.Lamp
        OdorFinalValveSwitch           matlab.ui.control.Switch
        OdorFinalValveSwitchLabel      matlab.ui.control.Label
        ContaminationFVLamp            matlab.ui.control.Lamp
        ContaminationFinalValveSwitch  matlab.ui.control.Switch
        ContaminationFinalValveSwitchLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OdorFinalValveSwitch
        function OdorFinalValveSwitchValueChanged(app, event)
            value = app.OdorFinalValveSwitch.Value;
            if value == "On"
                app.OdorFVLamp.Color = [0, 1, 0];
            else
                app.OdorFVLamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: ContaminationFinalValveSwitch
        function ContaminationFinalValveSwitchValueChanged(app, event)
            value = app.ContaminationFinalValveSwitch.Value;
            if value == "On"
                app.ContaminationFVLamp.Color = [0, 1, 0];
            else
                app.ContaminationFVLamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve1Switch
        function Valve1SwitchValueChanged(app, event)
            value = app.Valve1Switch.Value;
            if value == "On"
                app.Valve1Lamp.Color = [0, 1, 0];
            else
                app.Valve1Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve2Switch
        function Valve2SwitchValueChanged(app, event)
            value = app.Valve2Switch.Value;
            if value == "On"
                app.Valve2Lamp.Color = [0, 1, 0];
            else
                app.Valve2Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve3Switch
        function Valve3SwitchValueChanged(app, event)
            value = app.Valve3Switch.Value;

            if value == "On"
                app.Valve3Lamp.Color = [0, 1, 0];
            else
                app.Valve3Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve4Switch
        function Valve4SwitchValueChanged(app, event)
            value = app.Valve4Switch.Value;
            if value == "On"
                app.Valve4Lamp.Color = [0, 1, 0];
            else
                app.Valve4Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: PressurizeOdorVialSwitch
        function PressurizeOdorVialSwitchValueChanged(app, event)
            value = app.PressurizeOdorVialSwitch.Value;
            if value == "On"
                app.PressurizeOdorLamp.Color = [0, 1, 0];
                app.Valve3Lamp.Color = [0, 1, 0];
                app.Valve4Lamp.Color = [0, 1, 0];
                app.Valve3Switch.Value = "On";
                app.Valve4Switch.Value = "On";
                app.Valve3Switch.Enable = false;
                app.Valve4Switch.Enable = false;


            else
                app.PressurizeOdorLamp.Color = [1, 0, 0];
                app.Valve3Lamp.Color = [1, 0, 0];
                app.Valve4Lamp.Color = [1, 0, 0];
                app.Valve3Switch.Value = "Off";
                app.Valve4Switch.Value = "Off";
                app.Valve3Switch.Enable = true;
                app.Valve4Switch.Enable = true;


            end
        end

        % Value changed function: PressurizeSolventVialSwitch
        function PressurizeSolventVialSwitchValueChanged(app, event)
            value = app.PressurizeSolventVialSwitch.Value;
            if value == "On"
                app.PressurizeSolventLamp.Color = [0, 1, 0];
                app.Valve1Lamp.Color = [0, 1, 0];
                app.Valve2Lamp.Color = [0, 1, 0];
                app.Valve1Switch.Value = "On";
                app.Valve2Switch.Value = "On";
                app.Valve1Switch.Enable = false;
                app.Valve2Switch.Enable = false;

            else
                app.PressurizeSolventLamp.Color = [1, 0, 0];
                app.Valve1Lamp.Color = [1, 0, 0];
                app.Valve2Lamp.Color = [1, 0, 0];
                app.Valve1Switch.Value = "Off";
                app.Valve2Switch.Value = "Off";
                app.Valve1Switch.Enable = true;
                app.Valve2Switch.Enable = true;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.FinalValvesPanel = uipanel(app.ManualControlPanel);
            app.FinalValvesPanel.Title = 'Final Valves';
            app.ContaminationFinalValveSwitchLabel = uilabel(app.FinalValvesPanel);
            app.ContaminationFinalValveSwitchLabel.Text = 'Contamination Final Valve';
            app.ContaminationFinalValveSwitch = uiswitch(app.FinalValvesPanel, 'slider');
            app.ContaminationFinalValveSwitch.ValueChangedFcn = createCallbackFcn(app, @ContaminationFinalValveSwitchValueChanged, true);
            app.ContaminationFVLamp = uilamp(app.FinalValvesPanel);
            app.OdorFinalValveSwitchLabel = uilabel(app.FinalValvesPanel);
            app.OdorFinalValveSwitchLabel.Text = 'Odor Final Valve';
            app.OdorFinalValveSwitch = uiswitch(app.FinalValvesPanel, 'slider');
            app.OdorFinalValveSwitch.ValueChangedFcn = createCallbackFcn(app, @OdorFinalValveSwitchValueChanged, true);
            app.OdorFVLamp = uilamp(app.FinalValvesPanel);
            app.VialValvesPanel = uipanel(app.ManualControlPanel);
            app.VialValvesPanel.Title = 'Vial Valves';
            app.SolventPureVialPanel = uipanel(app.VialValvesPanel);
            app.SolventPureVialPanel.Title = 'Solvent / Pure Vial';
            app.Valve2SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve2SwitchLabel.Text = 'Valve 2';
            app.Valve2Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve2Switch.ValueChangedFcn = createCallbackFcn(app, @Valve2SwitchValueChanged, true);
            app.Valve2Lamp = uilamp(app.SolventPureVialPanel);
            app.Valve1SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve1SwitchLabel.Text = 'Valve 1';
            app.Valve1Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve1Switch.ValueChangedFcn = createCallbackFcn(app, @Valve1SwitchValueChanged, true);
            app.Valve1Lamp = uilamp(app.SolventPureVialPanel);
            app.OdorVialBottlePanel = uipanel(app.VialValvesPanel);
            app.OdorVialBottlePanel.Title = 'Odor Vial / Bottle';
            app.Valve4SwitchLabel = uilabel(app.OdorVialBottlePanel);
            app.Valve4SwitchLabel.Text = 'Valve 4';
            app.Valve4Switch = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve4Switch.ValueChangedFcn = createCallbackFcn(app, @Valve4SwitchValueChanged, true);
            app.Valve4Lamp = uilamp(app.OdorVialBottlePanel);
            app.Valve3SwitchLabel = uilabel(app.OdorVialBottlePanel);
            app.Valve3SwitchLabel.Text = 'Valve 3';
            app.Valve3Switch = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve3Switch.ValueChangedFcn = createCallbackFcn(app, @Valve3SwitchValueChanged, true);
            app.Valve3Lamp = uilamp(app.OdorVialBottlePanel);
            app.PresurizationPanel = uipanel(app.VialValvesPanel);
            app.PresurizationPanel.Title = 'Presurization';
            app.PressurizeSolventVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeSolventVialSwitchLabel.Text = 'Pressurize Solvent Vial';
            app.PressurizeSolventVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeSolventVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeSolventVialSwitchValueChanged, true);
            app.PressurizeSolventLamp = uilamp(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel.Text = 'Pressurize Odor Vial';
            app.PressurizeOdorVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeOdorVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeOdorVialSwitchValueChanged, true);
            app.PressurizeOdorLamp = uilamp(app.PresurizationPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_main_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end